classdef Group04 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Group04UIFigure                matlab.ui.Figure
        WarnLabel_2                    matlab.ui.control.Label
        WarnLabel                      matlab.ui.control.Label
        LowHighTresholdSlider          matlab.ui.control.RangeSlider
        CannyThresholdLabel            matlab.ui.control.Label
        OtsuCheckBox                   matlab.ui.control.CheckBox
        LoGThresholdLabel              matlab.ui.control.Label
        EditField_3                    matlab.ui.control.NumericEditField
        IncludeDiagonalsCheckBox       matlab.ui.control.CheckBox
        IntensityEditField             matlab.ui.control.NumericEditField
        IntensityEditFieldLabel        matlab.ui.control.Label
        SaturationEditField            matlab.ui.control.NumericEditField
        SaturationEditFieldLabel       matlab.ui.control.Label
        HueEditField                   matlab.ui.control.NumericEditField
        HueEditFieldLabel              matlab.ui.control.Label
        finalizeButton                 matlab.ui.control.Button
        operationBoxColormap           matlab.ui.control.ListBox
        nxnLabel                       matlab.ui.control.Label
        ThresholdforBinarizationLabel  matlab.ui.control.Label
        nxnEditField                   matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        exitButton                     matlab.ui.control.Button
        saveButton                     matlab.ui.control.Button
        applyButton                    matlab.ui.control.Button
        operationBox                   matlab.ui.control.ListBox
        Label                          matlab.ui.control.Label
        loadButton                     matlab.ui.control.Button
        imageFinal                     matlab.ui.control.UIAxes
        imageModified                  matlab.ui.control.UIAxes
        imageOriginal                  matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
 [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.pgm; *.ppm' , 'All Image Files'}); 
    if isequal(file, 0)
       disp('User selected Cancel');
    else
       img = imread(fullfile(path, file));
       % Görüntünün RGB olup olmadığını kontrol et
       if size(img, 3) == 3
           % RGB'den HSI'ye dönüşüm
           hsiImg = rgbtohsi(img);
       else
           % Görüntü gri tonlamadaysa, sahte bir HSI görüntü oluştur
           hsiImg = repmat(double(img) / 255, 1, 1, 3);
       end
       % HSI'nin normalize edilmesi
       normalizedHsi = mynormalize(hsiImg);

       % Resmi GUIde göster
       imshow(img, 'Parent', app.imageOriginal);
       app.applyButton.Enable = 'on';
       app.EditField.Enable = 'on';
       app.nxnEditField.Enable = 'on';
       app.EditField_3.Enable = 'on';
       app.operationBox.Enable = 'on';
       app.OtsuCheckBox.Enable = 'on';
       app.IncludeDiagonalsCheckBox.Enable = 'on' ;
       app.nxnLabel.Enable = 'on' ;
       app.LoGThresholdLabel.Enable = 'on' ;
       app.LowHighTresholdSlider.Enable = 'on' ;
       app.ThresholdforBinarizationLabel.Enable = 'on' ;

       % Ortalama HSI değerlerini hesapla ve göster
       avgHue = mean2(normalizedHsi(:,:,1));
       avgSaturation = mean2(normalizedHsi(:,:,2));
       avgIntensity = mean2(normalizedHsi(:,:,3));

       app.HueEditField.Value = avgHue;
       app.SaturationEditField.Value = avgSaturation;
       app.IntensityEditField.Value = avgIntensity;
    end

    
        end

        % Button pushed function: applyButton
        function applyButtonPushed(app, event)
            d = uiprogressdlg(app.Group04UIFigure, 'Title', 'Please Wait', ...
                      'Message', 'Processing...', ...
                      'Indeterminate', 'on');

    originalImage = getimage(app.imageOriginal);
    method = app.operationBox.Value;
    includeDiagonals = app.IncludeDiagonalsCheckBox.Value;

switch method
     case 'canny'
        thresholds = app.LowHighTresholdSlider.Value;
        lowThreshold = thresholds(1);
        highThreshold = thresholds(2);
        n = app.nxnEditField.Value;  

        if isnan(lowThreshold) || isnan(highThreshold) || isnan(n)
            uialert(app.Group04UIFigure, 'Invalid Canny parameters.', 'Parameter Error');
            return;
        end
        if lowThreshold < 0 || lowThreshold > 1 || highThreshold < 0 || highThreshold > 1 || n < 1
            uialert(app.Group04UIFigure, 'Invalid parameters. Thresholds must be between 0 and 1, n must be greater than 0.', 'Parameter Error');
            return;
        end

        if size(originalImage, 3) == 3
            grayImage = rgb2gray(originalImage);
        else
            grayImage = originalImage;
        end

        try
        processedImage = mycanny(grayImage, lowThreshold, highThreshold, n);
        catch ME
        uialert(app.Group04UIFigure, 'Error in edge detection: ' + string(ME.message), 'Processing Error');
        return;
        end
        imshow(processedImage, 'Parent', app.imageModified);

    case 'log'
        n = app.nxnEditField.Value; 
        threshold = app.EditField_3.Value;

        if isnan(n) || n < 1 || isnan(threshold) || threshold < 0 || threshold > 1
             uialert(app.Group04UIFigure, 'Invalid parameters.', 'Parameter Error');
             return;
        end
        grayImage = im2gray(originalImage);
        logKernel = fspecial('log', [n, n]);

            filteredImage = imfilter(grayImage, logKernel, 'symmetric');

            processedImage = filteredImage > threshold;

            imshow(processedImage, 'Parent', app.imageModified);
    otherwise

    if size(originalImage, 3) ~= 1
        grayImage = rgb2gray(originalImage);
    else
        grayImage = originalImage;
    end
    
    useOtsu = app.OtsuCheckBox.Value;
    threshold = app.EditField.Value;

    if ~useOtsu && (isnan(threshold) || threshold < 1 || threshold > 254)
        uialert(app.UIFigure, 'Threshold must be between 1 and 254.', 'Invalid Threshold');
        return;
    end

    try
        processedImage = myedge(grayImage, method, includeDiagonals, threshold / 255, useOtsu);
    catch ME
        uialert(app.Group04UIFigure, 'Error in edge detection: ' + string(ME.message), 'Processing Error');
        return;
    end

    imshow(processedImage, 'Parent', app.imageModified);
end
    app.finalizeButton.Enable = 'on';
    app.operationBoxColormap.Enable = "on";
     close(d);
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
   [file, path] = uiputfile('*.ppm', 'Save Image');

    if isequal(file, 0) || isequal(path, 0)
       disp('User pressed cancel')
    else
       frame = getframe(app.imageFinal);
       imwrite(frame.cdata, fullfile(path, file));
       disp(['Image saved to ', fullfile(path, file)])
    end
        end

        % Button pushed function: exitButton
        function exitButtonPushed(app, event)
    answer = uiconfirm(app.Group04UIFigure, 'Are you sure you want to close the application?', ...
                      'Exit?', 'Options', {'Yes', 'No'}, ...
                      'DefaultOption', 'Yes', 'CancelOption', 'No');

    % Kullanıcının cevabına göre işlem yap
    if strcmp(answer, 'Yes')
       delete(app);
    else
        
    end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            
        end

        % Value changed function: operationBox
        function operationBoxValueChanged(app, event)
            
        end

        % Size changed function: Group04UIFigure
        function Group04UIFigureSizeChanged(app, event)
       
        end

        % Value changed function: IncludeDiagonalsCheckBox
        function IncludeDiagonalsCheckBoxValueChanged(app, event)

        end

        % Button pushed function: finalizeButton
        function finalizeButtonPushed(app, event)
       binaryImage = getimage(app.imageModified);

    if isempty(binaryImage)
        uialert(app.Group04UIFigure, 'No processed image available.', 'Error');
        return;
    end

    selectedColormap = app.operationBoxColormap.Value;

    coloredImage = mymorph(binaryImage, selectedColormap);

    imshow(coloredImage, 'Parent', app.imageFinal);
    app.saveButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Group04UIFigure = uifigure('Visible', 'off');
            colormap(app.Group04UIFigure, 'turbo');
            app.Group04UIFigure.Name = 'Group04';
            app.Group04UIFigure.SizeChangedFcn = createCallbackFcn(app, @Group04UIFigureSizeChanged, true);
            app.imageOriginal = uiaxes(app.Group04UIFigure);
            title(app.imageOriginal, 'Original Image ')
            app.imageOriginal.XTick = [];
            app.imageOriginal.YTick = [];
            app.imageOriginal.SubtitleFontWeight = 'bold';
            colormap(app.imageOriginal, 'turbo')
            app.imageModified = uiaxes(app.Group04UIFigure);
            title(app.imageModified, 'Modified Image')
            app.imageModified.XTick = [];
            app.imageModified.YTick = [];
            colormap(app.imageModified, 'turbo')
            app.imageFinal = uiaxes(app.Group04UIFigure);
            title(app.imageFinal, 'Final Image')
            app.imageFinal.XTick = [];
            app.imageFinal.YTick = [];
            app.imageFinal.SubtitleFontWeight = 'bold';
            colormap(app.imageFinal, 'turbo')
            app.loadButton = uibutton(app.Group04UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'LOAD IMAGE';
            app.Label = uilabel(app.Group04UIFigure);
            app.Label.Text = ' ';
            app.operationBox = uilistbox(app.Group04UIFigure);
            app.operationBox.Items = {'kirsch', 'prewitt', 'scharr', 'sobel', 'log', 'canny'};
            app.operationBox.ValueChangedFcn = createCallbackFcn(app, @operationBoxValueChanged, true);
            app.operationBox.Enable = 'off';
            app.operationBox.Value = 'kirsch';
            app.applyButton = uibutton(app.Group04UIFigure, 'push');
            app.applyButton.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.applyButton.Enable = 'off';
            app.applyButton.Text = 'PROCESS';
            app.saveButton = uibutton(app.Group04UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Enable = 'off';
            app.saveButton.Text = 'SAVE IMAGE';
            app.exitButton = uibutton(app.Group04UIFigure, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exitButton.Text = 'EXIT';
            app.EditField = uieditfield(app.Group04UIFigure, 'numeric');
            app.EditField.Limits = [1 254];
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Enable = 'off';
            app.EditField.Value = 1;
            app.nxnEditField = uieditfield(app.Group04UIFigure, 'numeric');
            app.nxnEditField.Limits = [1 Inf];
            app.nxnEditField.ValueDisplayFormat = '%.0f';
            app.nxnEditField.Enable = 'off';
            app.nxnEditField.Value = 1;
            app.ThresholdforBinarizationLabel = uilabel(app.Group04UIFigure);
            app.ThresholdforBinarizationLabel.Enable = 'off';
            app.ThresholdforBinarizationLabel.Text = 'Threshold for Binarization';
            app.nxnLabel = uilabel(app.Group04UIFigure);
            app.nxnLabel.Enable = 'off';
            app.nxnLabel.Text = 'nxn';
            app.operationBoxColormap = uilistbox(app.Group04UIFigure);
            app.operationBoxColormap.Items = {'Jet', 'HSV', 'Hot', 'Cool', 'Parula'};
            app.operationBoxColormap.Enable = 'off';
            app.operationBoxColormap.Value = 'Jet';
            app.finalizeButton = uibutton(app.Group04UIFigure, 'push');
            app.finalizeButton.ButtonPushedFcn = createCallbackFcn(app, @finalizeButtonPushed, true);
            app.finalizeButton.Enable = 'off';
            app.finalizeButton.Text = 'FINALIZE';
            app.HueEditFieldLabel = uilabel(app.Group04UIFigure);
            app.HueEditFieldLabel.Text = 'Hue';
            app.HueEditField = uieditfield(app.Group04UIFigure, 'numeric');
            app.SaturationEditFieldLabel = uilabel(app.Group04UIFigure);
            app.SaturationEditFieldLabel.Text = 'Saturation';
            app.SaturationEditField = uieditfield(app.Group04UIFigure, 'numeric');
            app.IntensityEditFieldLabel = uilabel(app.Group04UIFigure);
            app.IntensityEditFieldLabel.Text = 'Intensity';
            app.IntensityEditField = uieditfield(app.Group04UIFigure, 'numeric');
            app.IncludeDiagonalsCheckBox = uicheckbox(app.Group04UIFigure);
            app.IncludeDiagonalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeDiagonalsCheckBoxValueChanged, true);
            app.IncludeDiagonalsCheckBox.Enable = 'off';
            app.IncludeDiagonalsCheckBox.Text = 'Include Diagonals';
            app.EditField_3 = uieditfield(app.Group04UIFigure, 'numeric');
            app.EditField_3.Limits = [0 1];
            app.EditField_3.Enable = 'off';
            app.LoGThresholdLabel = uilabel(app.Group04UIFigure);
            app.LoGThresholdLabel.Enable = 'off';
            app.LoGThresholdLabel.Text = 'LoG Threshold';
            app.OtsuCheckBox = uicheckbox(app.Group04UIFigure);
            app.OtsuCheckBox.Enable = 'off';
            app.OtsuCheckBox.Text = 'Use Otsu';
            app.CannyThresholdLabel = uilabel(app.Group04UIFigure);
            app.CannyThresholdLabel.Text = {'Canny '; 'Threshold'};
            app.LowHighTresholdSlider = uislider(app.Group04UIFigure, 'range');
            app.LowHighTresholdSlider.Limits = [0 1];
            app.LowHighTresholdSlider.Enable = 'off';
            app.LowHighTresholdSlider.Value = [1e-08 0.999999];
            app.WarnLabel = uilabel(app.Group04UIFigure);
            app.WarnLabel.Enable = 'off';
            app.WarnLabel.Text = '*If you selected "canny" or "LoG" fill in the fields below*';
            app.WarnLabel_2 = uilabel(app.Group04UIFigure);
            app.WarnLabel_2.Enable = 'off';
            app.WarnLabel_2.Text = '*If you selected "kirsch", "prewitt", "scharr" or "sobel", fill in the fields below*';
            % Show the figure after all components are created
            app.Group04UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group04

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Group04UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Group04UIFigure)
        end
    end
end